classdef Scara_Robot_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        robot_plot    matlab.ui.control.UIAxes
        title         matlab.ui.control.Label
        author        matlab.ui.control.Label
        TabGroup      matlab.ui.container.TabGroup
        ForwardTab    matlab.ui.container.Tab
        sld__FW_t1    matlab.ui.control.Slider
        sld__FW_t2    matlab.ui.control.Slider
        sld__FW_d3    matlab.ui.control.Slider
        sld__FW_t4    matlab.ui.control.Slider
        vl__FW_t1     matlab.ui.control.EditField
        vl__FW_t2     matlab.ui.control.EditField
        txt_FW_t1     matlab.ui.control.Label
        txt_FW_t2     matlab.ui.control.Label
        vl__FW_d3     matlab.ui.control.EditField
        txt_FW_d3     matlab.ui.control.Label
        vl__FW_t4     matlab.ui.control.EditField
        txt_FW_t4     matlab.ui.control.Label
        txt_FW_x      matlab.ui.control.Label
        vl__FW_x      matlab.ui.control.EditField
        txt_FW_y      matlab.ui.control.Label
        vl__FW_y      matlab.ui.control.EditField
        txt_FW_z      matlab.ui.control.Label
        vl__FW_z      matlab.ui.control.EditField
        txt_FW_yaw    matlab.ui.control.Label
        vl__FW_yaw    matlab.ui.control.EditField
        btn_FW_reset  matlab.ui.control.Button
        btn_FW_set    matlab.ui.control.Button
        InverseTab    matlab.ui.container.Tab
        sld_IV_x      matlab.ui.control.Slider
        sld_IV_y      matlab.ui.control.Slider
        sld_IV_z      matlab.ui.control.Slider
        sld_IV_yaw    matlab.ui.control.Slider
        vl__IV_yaw    matlab.ui.control.EditField
        txt_IV_yaw    matlab.ui.control.Label
        vl__IV_z      matlab.ui.control.EditField
        text18        matlab.ui.control.Label
        vl__IV_y      matlab.ui.control.EditField
        txt_IV_y      matlab.ui.control.Label
        vl__IV_x      matlab.ui.control.EditField
        txt_IV_x      matlab.ui.control.Label
        btn_IV_reset  matlab.ui.control.Button
        btn_IV_set    matlab.ui.control.Button
        txt_IV_t1     matlab.ui.control.Label
        txt_IV_t2     matlab.ui.control.Label
        vl__IV_t1     matlab.ui.control.EditField
        vl__IV_t2     matlab.ui.control.EditField
        txt_IV_d3     matlab.ui.control.Label
        txt_IV_t4     matlab.ui.control.Label
        vl__IV_d3     matlab.ui.control.EditField
        vl__IV_t4     matlab.ui.control.EditField
        PlanningTab   matlab.ui.container.Tab
    end

    
    methods (Access = private)
        function vl_t1_CreateFcn(app, hObject, eventdata, handles)
            
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function vl_t2_CreateFcn(app, hObject, eventdata, handles)
            
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function vl_t4_CreateFcn(app, hObject, eventdata, handles)
            
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Scara_Robot_OpeningFcn(app)
            
            
            global a alpha d theta;
            global pos orien;
            
            a     = [0.45;    0.40;     0.00;      0.00];
            alpha = [0.00;    0.00;     0.00;      180];
            d     = [0.46;    0.00;     0.00;      0.00];
            theta = [0.00;     90;     0.00;      0.00];
            [pos,orien] = ForwardKinematic(a, alpha*pi/180, d, theta*pi/180)
            %output (x, y, z, yaw)
            set(app.vl__FW_x,'value',num2str(pos(4,1)));
            set(app.vl__FW_y,'value',num2str(pos(4,2)));
            set(app.vl__FW_z,'value',num2str(pos(4,3)));
            set(app.vl__FW_yaw,'value',num2str(orien(4,3)*180/pi));
            UpdateRobot_App(app.robot_plot,pos,orien,11,11);
        end

        % Button pushed function: btn_FW_reset
        function btn_FW_reset_Callback(app, event)
            
            global a alpha d theta pos orien;
            set(app.sld__FW_t1, 'value', 0);
            set(app.sld__FW_t2, 'value', 90);
            set(app.sld__FW_d3, 'value', 0);
            set(app.sld__FW_t4, 'value', 0);
            set(app.vl__FW_t1,'value','0');
            set(app.vl__FW_t2,'value','90');
            set(app.vl__FW_d3,'value','0');
            set(app.vl__FW_t4,'value','0');
            
            theta(1) = 0;
            theta(2) = 90;
            d(3) = 0;
            theta(4) = 0;
            [pos,orien] = ForwardKinematic(a, alpha*pi/180, d, theta*pi/180)
            
            
            %output (x, y, z, yaw)
            set(app.vl__FW_x,'value',num2str(pos(4,1)));
            set(app.vl__FW_y,'value',num2str(pos(4,2)));
            set(app.vl__FW_z,'value',num2str(pos(4,3)));
            set(app.vl__FW_yaw,'value',num2str(orien(4,3)*180/pi));
            UpdateRobot_App(app.robot_plot,pos,orien,11,11);
        end

        % Button pushed function: btn_FW_set
        function btn_FW_set_Callback(app, event)
            
            global a alpha d theta pos orien;
            theta(1) = str2double(get(app.vl__FW_t1,'value'));
            theta(2) = str2double(get(app.vl__FW_t2,'value'));
            d(3) = str2double(get(app.vl__FW_d3,'value'));
            theta(4) = str2double(get(app.vl__FW_t4,'value'));
            [pos,orien] = ForwardKinematic(a, alpha*pi/180, d, theta*pi/180)
            
            %output (x, y, z, yaw)
            set(app.vl__FW_x,'value',num2str(pos(4,1)));
            set(app.vl__FW_y,'value',num2str(pos(4,2)));
            set(app.vl__FW_z,'value',num2str(pos(4,3)));
            set(app.vl__FW_yaw,'value',num2str(orien(4,3)*180/pi));
            UpdateRobot_App(app.robot_plot,pos,orien,11,11);
        end

        % Callback function
        function btn_Forward_Callback(app, event)
            

            set(app.btn_Forward,'BackgroundColor','white');
            set(app.btn_Inverse,'BackgroundColor',[0.94 0.94 0.94]);
            set(app.btn_Planning,'BackgroundColor',[0.94 0.94 0.94]);
        end

        % Button pushed function: btn_IV_reset
        function btn_IV_reset_Callback(app, event)
            
            global a alpha d theta pos orien
                set(app.vl__IV_x,'value','0.45');
                set(app.vl__IV_y,'value','0.40');
                set(app.vl__IV_z,'value','0.46');
                set(app.vl__IV_yaw,'value','90');
            
                theta(1) = 0;
                theta(2) = 90;
                d3 = 0;
                theta(4) = 0;
            
                [pos,orien] = ForwardKinematic(a, alpha*pi/180, d, theta*pi/180)
                UpdateRobot_App(app.robot_plot,pos,orien,11,11);
                
                set(app.tb_pos_orien,'Data',[pos orien*180/pi]);
        end

        % Button pushed function: btn_IV_set
        function btn_IV_set_Callback(app, event)
            
            global a alpha d theta pos orien
            x = str2double(get(app.vl__IV_x,'value'));
            y = str2double(get(app.vl__IV_y,'value'));
            z = str2double(get(app.vl__IV_z,'value'));
            yaw = str2double(get(app.vl__IV_yaw,'value'));
            [theta(1),theta(2),d(3),theta(4),sucess] = InverseKinematic(x,y,z,yaw/180*pi,a(1),a(2),d(1));
            if sucess
                theta(1) = theta(1)*180/pi;
                theta(2) = theta(2)*180/pi;
                theta(4) = theta(4)*180/pi;
            
                [pos,orien] = ForwardKinematic(a, alpha*pi/180, d, theta*pi/180)
                set(app.vl__IV_t1,'value',num2str(theta(1)));
                set(app.vl__IV_t2,'value',num2str(theta(2)));
                set(app.vl__IV_d3,'value',num2str(d(3)));
                set(app.vl__IV_t4,'value',num2str(theta(4)));
                UpdateRobot_App(app.robot_plot,pos,orien,11,11);
                
                
            end
        end

        % Callback function
        function btn_Inverse_Callback(app, event)
            
            set(app.btn_Inverse,'BackgroundColor','white');
            set(app.btn_Forward,'BackgroundColor',[0.94 0.94 0.94]);
            set(app.btn_Planning,'BackgroundColor',[0.94 0.94 0.94]);
        end

        % Callback function
        function btn_Planning_Callback(app, event)
            
            set(app.btn_Planning,'BackgroundColor','white');
            set(app.btn_Inverse,'BackgroundColor',[0.94 0.94 0.94]);
            set(app.btn_Forward,'BackgroundColor',[0.94 0.94 0.94]);
        end

        % Callback function
        function btn_reset_constant_Callback(app, event)
            
            global a alpha d theta;
            set(app.vl_a1,'value','0.45');
            set(app.vl_a2,'value','0.40');
            set(app.vl_d1,'value','0.46');
            
            a(1) = 0.45;
            a(2) = 0.40;
            d(1) = 0.46;
            set(app.tb_dh,'Data',[a alpha d theta]);
        end

        % Callback function
        function btn_set_constant_Callback(app, event)
            
            global a alpha d theta;
            a(1) = str2double(get(app.vl_a1,'value'));
            a(2) = str2double(get(app.vl_a2,'value'));
            d(1) = str2double(get(app.vl_d1,'value'));
            set(app.tb_dh,'Data',[a alpha d theta]);
        end

        % Callback function
        function cb_show_coor_Callback(app, event)
            
            global pos orien
            if get(hObject, 'Value')
                axes(app.robot_plot);
                % plot coordinate
                plot_coordinate(0,0,0.5,-1,-1,1,0);
                plot_coordinate(pos(1,1),pos(1,2),pos(1,3)+0.5,1,1,1,1);
                plot_coordinate(pos(2,1),pos(2,2),pos(2,3)+0.5,1,1,1,2);
                plot_coordinate(pos(3,1),pos(3,2),pos(3,3)+0.5,1,1,1,3);
                plot_coordinate(pos(4,1),pos(4,2),pos(4,3)+0.5,-1,1,-1,4);
            else
                UpdateRobot_App(app.robot_plot,pos,orien,11,11);
            end
        end

        % Callback function
        function cb_view_ws_Callback(app, event)
            
            global a pos orien;
            if get(hObject, 'Value')
                r_min = abs(a(1) - a(2));
                r_max = a(1) + a(2);
                axes(app.robot_plot);
                gama = linspace(-pi,pi,181);
                z = 0.04:0.07:0.46;
                for i = 1:length(z)
                    plot3(r_min*cos(gama),r_min*sin(gama),z(i)*ones(181,1),'r','LineWidth',3);
                    plot3(r_max*cos(gama),r_max*sin(gama),z(i)*ones(181,1),'r','LineWidth',3);
                end
            else
                UpdateRobot_App(app.robot_plot,pos,orien,11,11);
            end
        end

        % Value changed function: sld_IV_x
        function sld_IV_x_Callback(app, event)
            
            x = get(app.sld_IV_x,'value');
            set(app.vl__IV_x,'value',num2str(x));
        end

        % Value changed function: sld_IV_y
        function sld_IV_y_Callback(app, event)
            
            y = get(app.sld_IV_y,'value');
            set(app.vl__IV_y,'value',num2str(y));
        end

        % Value changed function: sld_IV_yaw
        function sld_IV_yaw_Callback(app, event)
            
            yaw = get(app.sld_IV_yaw,'value');
            set(app.vl__IV_yaw,'value',num2str(yaw));
        end

        % Value changed function: sld_IV_z
        function sld_IV_z_Callback(app, event)
            
            z = get(app.sld_IV_z,'value');
            set(app.vl__IV_z,'value',num2str(z));
        end

        % Value changed function: sld__FW_d3
        function sld__FW_d3_Callback(app, event)
            
            d3 = get(app.sld__FW_d3,'value');
            set(app.vl__FW_d3,'value',num2str(d3));
        end

        % Value changed function: sld__FW_t1
        function sld__FW_t1_Callback(app, event)
            
            theta1 = get(app.sld__FW_t1,'value');
            set(app.vl__FW_t1,'value',num2str(theta1));
        end

        % Value changed function: sld__FW_t2
        function sld__FW_t2_Callback(app, event)
            
            theta(2) = get(app.sld__FW_t2,'value');
            set(app.vl__FW_t2,'value',num2str(theta(2)));
        end

        % Value changed function: sld__FW_t4
        function sld__FW_t4_Callback(app, event)
            
            theta(4) = get(app.sld__FW_t4,'value');
            set(app.vl__FW_t4,'value',num2str(theta(4)));
        end

        % Value changed function: vl__FW_d3
        function vl__FW_d3_Callback(app, event)
            
            d(3) = str2double(get(app.vl__FW_d3,'value'));
            set(app.sld__FW_d3, 'value', d(3));
        end

        % Value changed function: vl__FW_t1
        function vl__FW_t1_Callback(app, event)
            
            theta1 = str2double(get(app.vl__FW_t1,'value'));
            set(app.sld__FW_t1, 'value', theta1);
        end

        % Value changed function: vl__FW_t2
        function vl__FW_t2_Callback(app, event)
            
            theta(2) = str2double(get(app.vl__FW_t2,'value'));
            set(app.sld__FW_t2, 'value', theta(2));
        end

        % Value changed function: vl__FW_t4
        function vl__FW_t4_Callback(app, event)
            
            theta4 = str2double(get(app.vl__FW_t4,'value'));
            set(app.sld__FW_t4, 'value', theta4);
        end

        % Value changed function: vl__IV_x
        function vl__IV_x_Callback(app, event)
            
            x = str2double(get(hObject,'value'));
            set(app.sld_IV_x, 'value', x);
        end

        % Value changed function: vl__IV_y
        function vl__IV_y_Callback(app, event)
            
            y = str2double(get(hObject,'value'));
            set(app.sld_IV_y, 'value', y);
        end

        % Value changed function: vl__IV_yaw
        function vl__IV_yaw_Callback(app, event)
            
            yaw = str2double(get(hObject,'value'));
            set(app.sld_IV_yaw, 'value', yaw);
        end

        % Value changed function: vl__IV_z
        function vl__IV_z_Callback(app, event)
            
            z = str2double(get(hObject,'value'));
            set(app.sld_IV_z, 'value', z);
        end

        % Callback function
        function vl_a1_Callback(app, event)
            
            a(1) = str2double(get(app.vl_a1,'value'));
        end

        % Callback function
        function vl_a2_Callback(app, event)
            
            a(2) = str2double(get(app.vl_a2,'value'));
        end

        % Callback function
        function vl_d1_Callback(app, event)
            
            d(1) = str2double(get(app.vl_d1,'value'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Scara_Robot';
            app.robot_plot = uiaxes(app.figure1);
            app.title = uilabel(app.figure1);
            app.title.Text = 'Scara Robot Simulation';
            app.author = uilabel(app.figure1);
            app.author.Text = 'For more detail, contact me at nttoan.sdh21@hcmut.edu.vn';
            app.TabGroup = uitabgroup(app.figure1);
            app.ForwardTab = uitab(app.TabGroup);
            app.ForwardTab.Title = 'Forward';
            app.sld__FW_t1 = uislider(app.ForwardTab);
            app.sld__FW_t1.Limits = [-180 180];
            app.sld__FW_t1.MajorTicks = [];
            app.sld__FW_t1.ValueChangedFcn = createCallbackFcn(app, @sld__FW_t1_Callback, true);
            app.sld__FW_t1.MinorTicks = [];
            app.sld__FW_t2 = uislider(app.ForwardTab);
            app.sld__FW_t2.Limits = [-180 180];
            app.sld__FW_t2.MajorTicks = [];
            app.sld__FW_t2.ValueChangedFcn = createCallbackFcn(app, @sld__FW_t2_Callback, true);
            app.sld__FW_t2.MinorTicks = [];
            app.sld__FW_t2.Value = 90;
            app.sld__FW_d3 = uislider(app.ForwardTab);
            app.sld__FW_d3.Limits = [-0.42 0];
            app.sld__FW_d3.MajorTicks = [];
            app.sld__FW_d3.ValueChangedFcn = createCallbackFcn(app, @sld__FW_d3_Callback, true);
            app.sld__FW_d3.MinorTicks = [];
            app.sld__FW_t4 = uislider(app.ForwardTab);
            app.sld__FW_t4.Limits = [-180 180];
            app.sld__FW_t4.MajorTicks = [];
            app.sld__FW_t4.ValueChangedFcn = createCallbackFcn(app, @sld__FW_t4_Callback, true);
            app.sld__FW_t4.MinorTicks = [];
            app.vl__FW_t1 = uieditfield(app.ForwardTab, 'text');
            app.vl__FW_t1.ValueChangedFcn = createCallbackFcn(app, @vl__FW_t1_Callback, true);
            app.vl__FW_t1.Value = '0';
            app.vl__FW_t2 = uieditfield(app.ForwardTab, 'text');
            app.vl__FW_t2.ValueChangedFcn = createCallbackFcn(app, @vl__FW_t2_Callback, true);
            app.vl__FW_t2.Value = '90';
            app.txt_FW_t1 = uilabel(app.ForwardTab);
            app.txt_FW_t1.Text = 'theta 1';
            app.txt_FW_t2 = uilabel(app.ForwardTab);
            app.txt_FW_t2.Text = 'theta 2';
            app.vl__FW_d3 = uieditfield(app.ForwardTab, 'text');
            app.vl__FW_d3.ValueChangedFcn = createCallbackFcn(app, @vl__FW_d3_Callback, true);
            app.vl__FW_d3.Value = '0';
            app.txt_FW_d3 = uilabel(app.ForwardTab);
            app.txt_FW_d3.Text = 'd 3';
            app.vl__FW_t4 = uieditfield(app.ForwardTab, 'text');
            app.vl__FW_t4.ValueChangedFcn = createCallbackFcn(app, @vl__FW_t4_Callback, true);
            app.vl__FW_t4.Value = '0';
            app.txt_FW_t4 = uilabel(app.ForwardTab);
            app.txt_FW_t4.Text = 'theta 4';
            app.txt_FW_x = uilabel(app.ForwardTab);
            app.txt_FW_x.Text = 'x';
            app.vl__FW_x = uieditfield(app.ForwardTab, 'text');
            app.vl__FW_x.Value = '0';
            app.txt_FW_y = uilabel(app.ForwardTab);
            app.txt_FW_y.Text = 'y';
            app.vl__FW_y = uieditfield(app.ForwardTab, 'text');
            app.vl__FW_y.Value = '0';
            app.txt_FW_z = uilabel(app.ForwardTab);
            app.txt_FW_z.Text = 'z';
            app.vl__FW_z = uieditfield(app.ForwardTab, 'text');
            app.vl__FW_z.Value = '0';
            app.txt_FW_yaw = uilabel(app.ForwardTab);
            app.txt_FW_yaw.Text = 'yaw';
            app.vl__FW_yaw = uieditfield(app.ForwardTab, 'text');
            app.vl__FW_yaw.Value = '0';
            app.btn_FW_reset = uibutton(app.ForwardTab, 'push');
            app.btn_FW_reset.ButtonPushedFcn = createCallbackFcn(app, @btn_FW_reset_Callback, true);
            app.btn_FW_reset.Text = 'RESET';
            app.btn_FW_set = uibutton(app.ForwardTab, 'push');
            app.btn_FW_set.ButtonPushedFcn = createCallbackFcn(app, @btn_FW_set_Callback, true);
            app.btn_FW_set.Text = 'SET';
            app.InverseTab = uitab(app.TabGroup);
            app.InverseTab.Title = 'Inverse';
            app.sld_IV_x = uislider(app.InverseTab);
            app.sld_IV_x.Limits = [0.05 0.85];
            app.sld_IV_x.MajorTicks = [];
            app.sld_IV_x.ValueChangedFcn = createCallbackFcn(app, @sld_IV_x_Callback, true);
            app.sld_IV_x.MinorTicks = [];
            app.sld_IV_x.Value = 0.45;
            app.sld_IV_y = uislider(app.InverseTab);
            app.sld_IV_y.Limits = [0.05 0.85];
            app.sld_IV_y.MajorTicks = [];
            app.sld_IV_y.ValueChangedFcn = createCallbackFcn(app, @sld_IV_y_Callback, true);
            app.sld_IV_y.MinorTicks = [];
            app.sld_IV_y.Value = 0.4;
            app.sld_IV_z = uislider(app.InverseTab);
            app.sld_IV_z.Limits = [0.04 0.46];
            app.sld_IV_z.MajorTicks = [];
            app.sld_IV_z.ValueChangedFcn = createCallbackFcn(app, @sld_IV_z_Callback, true);
            app.sld_IV_z.MinorTicks = [];
            app.sld_IV_z.Value = 0.46;
            app.sld_IV_yaw = uislider(app.InverseTab);
            app.sld_IV_yaw.Limits = [-180 180];
            app.sld_IV_yaw.MajorTicks = [];
            app.sld_IV_yaw.ValueChangedFcn = createCallbackFcn(app, @sld_IV_yaw_Callback, true);
            app.sld_IV_yaw.MinorTicks = [];
            app.sld_IV_yaw.Value = 90;
            app.vl__IV_yaw = uieditfield(app.InverseTab, 'text');
            app.vl__IV_yaw.ValueChangedFcn = createCallbackFcn(app, @vl__IV_yaw_Callback, true);
            app.vl__IV_yaw.Value = '90';
            app.txt_IV_yaw = uilabel(app.InverseTab);
            app.txt_IV_yaw.Text = 'yaw';
            app.vl__IV_z = uieditfield(app.InverseTab, 'text');
            app.vl__IV_z.ValueChangedFcn = createCallbackFcn(app, @vl__IV_z_Callback, true);
            app.vl__IV_z.Value = '0.46';
            app.text18 = uilabel(app.InverseTab);
            app.text18.Text = 'z';
            app.vl__IV_y = uieditfield(app.InverseTab, 'text');
            app.vl__IV_y.ValueChangedFcn = createCallbackFcn(app, @vl__IV_y_Callback, true);
            app.vl__IV_y.Value = '0.4';
            app.txt_IV_y = uilabel(app.InverseTab);
            app.txt_IV_y.Text = 'y';
            app.vl__IV_x = uieditfield(app.InverseTab, 'text');
            app.vl__IV_x.ValueChangedFcn = createCallbackFcn(app, @vl__IV_x_Callback, true);
            app.vl__IV_x.Value = '0.45';
            app.txt_IV_x = uilabel(app.InverseTab);
            app.txt_IV_x.Text = 'x';
            app.btn_IV_reset = uibutton(app.InverseTab, 'push');
            app.btn_IV_reset.ButtonPushedFcn = createCallbackFcn(app, @btn_IV_reset_Callback, true);
            app.btn_IV_reset.Text = 'RESET';
            app.btn_IV_set = uibutton(app.InverseTab, 'push');
            app.btn_IV_set.ButtonPushedFcn = createCallbackFcn(app, @btn_IV_set_Callback, true);
            app.btn_IV_set.Text = 'SET';
            app.txt_IV_t1 = uilabel(app.InverseTab);
            app.txt_IV_t1.Text = 'theta 1';
            app.txt_IV_t2 = uilabel(app.InverseTab);
            app.txt_IV_t2.Text = 'theta 2';
            app.vl__IV_t1 = uieditfield(app.InverseTab, 'text');
            app.vl__IV_t1.Value = '0';
            app.vl__IV_t2 = uieditfield(app.InverseTab, 'text');
            app.vl__IV_t2.Value = '90';
            app.txt_IV_d3 = uilabel(app.InverseTab);
            app.txt_IV_d3.Text = 'd 3';
            app.txt_IV_t4 = uilabel(app.InverseTab);
            app.txt_IV_t4.Text = 'theta 4';
            app.vl__IV_d3 = uieditfield(app.InverseTab, 'text');
            app.vl__IV_d3.Value = '0';
            app.vl__IV_t4 = uieditfield(app.InverseTab, 'text');
            app.vl__IV_t4.Value = '0';
            app.PlanningTab = uitab(app.TabGroup);
            app.PlanningTab.Title = 'Planning';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Scara_Robot_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Scara_Robot_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end